classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BeamBendingShearSimulatorUIFigure  matlab.ui.Figure
        Results                         matlab.ui.control.Label
        MaxBending                      matlab.ui.control.NumericEditField
        MaxBendingMomentNmEditFieldLabel  matlab.ui.control.Label
        MaxShear                        matlab.ui.control.NumericEditField
        MaxShearForceNEditFieldLabel    matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        Drop                            matlab.ui.control.DropDown
        BeamMaterialDropDownLabel       matlab.ui.control.Label
        Load                            matlab.ui.control.NumericEditField
        LoadPositioninmetersEditFieldLabel  matlab.ui.control.Label
        Point                           matlab.ui.control.NumericEditField
        PointLoadinNewtonsEditFieldLabel  matlab.ui.control.Label
        BeamLength                      matlab.ui.control.NumericEditField
        BeamLengthinmetersEditFieldLabel  matlab.ui.control.Label
        BeamBendingShearSimulatorLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BeamLength
        function BeamLengthValueChanged(app, event)
            value = app.BeamLength.Value;
            
        end

        % Button down function: BeamBendingShearSimulatorUIFigure
        function BeamBendingShearSimulatorUIFigureButtonDown(app, event)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
        % Reset Input Fields to 0
            app.BeamLength.Value = 0;
            app.Point.Value = 0;       
            app.Load.Value = 0;        
            app.Drop.Value = 'Steel';  
            
            % Reset Output Fields to 0
            app.MaxShear.Value = 0;
            app.MaxBending.Value = 0;
            
            % Reset axes
            cla(app.UIAxes);
            title(app.UIAxes, 'Shear & Bending Moment Diagrams');
            xlabel(app.UIAxes, 'Beam Length (m)');
            ylabel(app.UIAxes, 'Magnitude');
      
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % 1. FETCH NUMERIC INPUTS
            L = app.BeamLength.Value;
            F = app.Point.Value;        
            x_load = app.Load.Value;    
            material = app.Drop.Value;  
            
            % 2. INPUT VALIDATION
            if L <= 0 || F <= 0
                uialert(app.BeamBendingShearSimulatorUIFigure, 'Beam Length and Point Load must be greater than zero.', 'Input Error');
                return; 
            end
            if x_load > L || x_load < 0
                uialert(app.BeamBendingShearSimulatorUIFigure, 'Load Position cannot be less than 0 or greater than the Beam Length.', 'Position Error');
                return; 
            end
            
            % 3. ENGINEERING COMPUTATIONS
            R1 = F * (L - x_load) / L; 
            R2 = F * x_load / L;       
            max_shear = max(R1, R2);
            if x_load == 0 || x_load == L
                max_moment = 0;
            else
                max_moment = R1 * x_load;
            end
            
            % 4. UPDATE OUTPUT UI FIELDS
            app.MaxShear.Value = max_shear;
            app.MaxBending.Value = max_moment;
            
            % 5. GENERATE DATA POINTS FOR THE PLOT
            x_vec = linspace(0, L, 1000); 
            M_vec = zeros(size(x_vec));   
            for i = 1:length(x_vec)
                x = x_vec(i);
                if x < x_load
                    M_vec(i) = R1 * x;
                else
                    M_vec(i) = R2 * (L - x);
                end
            end
            
            % 6. PLOT DATA TO UIAXES
            plot(app.UIAxes, x_vec, M_vec, 'b-', 'LineWidth', 2);
            grid(app.UIAxes, 'on');
            title(app.UIAxes, ['Bending Moment Diagram (' material ' Beam)']);
            xlabel(app.UIAxes, 'Beam Length (m)');
            ylabel(app.UIAxes, 'Bending Moment (N·m)');
    

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BeamBendingShearSimulatorUIFigure = uifigure('Visible', 'off');
            app.BeamBendingShearSimulatorUIFigure.Name = 'Beam Bending & Shear Simulator';
            app.BeamBendingShearSimulatorUIFigure.ButtonDownFcn = createCallbackFcn(app, @BeamBendingShearSimulatorUIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.BeamBendingShearSimulatorUIFigure);
            title(app.UIAxes, 'Shear & Bending Moment Diagrams')
            xlabel(app.UIAxes, 'Beam Length (m)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.BeamBendingShearSimulatorLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.BeamBendingShearSimulatorLabel.Text = 'Beam Bending & Shear Simulator';
            app.BeamLengthinmetersEditFieldLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.BeamLengthinmetersEditFieldLabel.Text = 'Beam Length( in meters)';
            app.BeamLength = uieditfield(app.BeamBendingShearSimulatorUIFigure, 'numeric');
            app.BeamLength.ValueChangedFcn = createCallbackFcn(app, @BeamLengthValueChanged, true);
            app.PointLoadinNewtonsEditFieldLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.PointLoadinNewtonsEditFieldLabel.Text = 'Point Load(in Newtons)';
            app.Point = uieditfield(app.BeamBendingShearSimulatorUIFigure, 'numeric');
            app.LoadPositioninmetersEditFieldLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.LoadPositioninmetersEditFieldLabel.Text = 'Load Position (in meters)';
            app.Load = uieditfield(app.BeamBendingShearSimulatorUIFigure, 'numeric');
            app.BeamMaterialDropDownLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.BeamMaterialDropDownLabel.Text = 'Beam Material';
            app.Drop = uidropdown(app.BeamBendingShearSimulatorUIFigure);
            app.Drop.Items = {'Steel', 'Aluminium', 'Wood'};
            app.Drop.Value = 'Steel';
            app.CalculateButton = uibutton(app.BeamBendingShearSimulatorUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.BeamBendingShearSimulatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MaxShearForceNEditFieldLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.MaxShearForceNEditFieldLabel.Text = 'Max Shear Force (N)';
            app.MaxShear = uieditfield(app.BeamBendingShearSimulatorUIFigure, 'numeric');
            app.MaxShear.Editable = 'off';
            app.MaxBendingMomentNmEditFieldLabel = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.MaxBendingMomentNmEditFieldLabel.Text = 'Max Bending Moment (N·m)';
            app.MaxBending = uieditfield(app.BeamBendingShearSimulatorUIFigure, 'numeric');
            app.MaxBending.Editable = 'off';
            app.Results = uilabel(app.BeamBendingShearSimulatorUIFigure);
            app.Results.Text = 'Results';
            % Show the figure after all components are created
            app.BeamBendingShearSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BeamBendingShearSimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BeamBendingShearSimulatorUIFigure)
        end
    end
end