classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GSoftwareUIFigure      matlab.ui.Figure
        Answer                 matlab.ui.control.Label
        Mul                    matlab.ui.control.Button
        Div                    matlab.ui.control.Button
        Sub                    matlab.ui.control.Button
        Add                    matlab.ui.control.Button
        txtNum2                matlab.ui.control.NumericEditField
        Number2EditFieldLabel  matlab.ui.control.Label
        txtNum1                matlab.ui.control.NumericEditField
        Number1EditFieldLabel  matlab.ui.control.Label
        BKSPButton             matlab.ui.control.Button
        Wizzo                  matlab.ui.control.Button
        lblDisplay             matlab.ui.control.Label
        btnGreat               matlab.ui.control.Button
        txtName                matlab.ui.control.EditField
        NameEditFieldLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGreat
        function btnGreatButtonPushed(app, event)
            name = app.txtName.Value;
            app.lblDisplay.Text= "Hello" + name +". Welcome to JEDS";
        end

        % Button pushed function: Wizzo
        function WizzoButtonPushed(app, event)
            app.txtName.Value = "" ;
            app.lblDisplay.Text = "" ;
        end

        % Button pushed function: BKSPButton
        function BKSPButtonPushed(app, event)
          app.txtName.Value = app.txtName.Value(1 : length(app.txtName.Value)-1);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            app.Answer.Text = string(app.txtNum1.Value + app.txtNum2.Value);
        end

        % Button pushed function: Sub
        function SubButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GSoftwareUIFigure = uifigure('Visible', 'off');
            app.GSoftwareUIFigure.Name = 'G Software';
            app.NameEditFieldLabel = uilabel(app.GSoftwareUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.txtName = uieditfield(app.GSoftwareUIFigure, 'text');
            app.btnGreat = uibutton(app.GSoftwareUIFigure, 'push');
            app.btnGreat.ButtonPushedFcn = createCallbackFcn(app, @btnGreatButtonPushed, true);
            app.btnGreat.Text = 'Great Me';
            app.lblDisplay = uilabel(app.GSoftwareUIFigure);
            app.lblDisplay.Text = '';
            app.Wizzo = uibutton(app.GSoftwareUIFigure, 'push');
            app.Wizzo.ButtonPushedFcn = createCallbackFcn(app, @WizzoButtonPushed, true);
            app.Wizzo.Text = 'Clear';
            app.BKSPButton = uibutton(app.GSoftwareUIFigure, 'push');
            app.BKSPButton.ButtonPushedFcn = createCallbackFcn(app, @BKSPButtonPushed, true);
            app.BKSPButton.Text = 'BKSP';
            app.Number1EditFieldLabel = uilabel(app.GSoftwareUIFigure);
            app.Number1EditFieldLabel.Text = 'Number 1';
            app.txtNum1 = uieditfield(app.GSoftwareUIFigure, 'numeric');
            app.Number2EditFieldLabel = uilabel(app.GSoftwareUIFigure);
            app.Number2EditFieldLabel.Text = 'Number 2';
            app.txtNum2 = uieditfield(app.GSoftwareUIFigure, 'numeric');
            app.Add = uibutton(app.GSoftwareUIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = '+';
            app.Sub = uibutton(app.GSoftwareUIFigure, 'push');
            app.Sub.ButtonPushedFcn = createCallbackFcn(app, @SubButtonPushed, true);
            app.Sub.Text = '-';
            app.Div = uibutton(app.GSoftwareUIFigure, 'push');
            app.Div.Text = '/';
            app.Mul = uibutton(app.GSoftwareUIFigure, 'push');
            app.Mul.Text = 'x';
            app.Answer = uilabel(app.GSoftwareUIFigure);
            app.Answer.Text = '';
            % Show the figure after all components are created
            app.GSoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GSoftwareUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GSoftwareUIFigure)
        end
    end
end